classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PanelAyuda                 matlab.ui.container.Panel
        ObservalamagiaLabel        matlab.ui.control.Label
        EjecutaelprogramaLabel     matlab.ui.control.Label
        SeleccionaelalgoritmodeseadoLabel  matlab.ui.control.Label
        Image7_2                   matlab.ui.control.Image
        Image9                     matlab.ui.control.Image
        Image8                     matlab.ui.control.Image
        TextArea                   matlab.ui.control.TextArea
        CerrarButton               matlab.ui.control.Button
        AyudaLabel                 matlab.ui.control.Label
        Button                     matlab.ui.control.Button
        IniciarButton_Profundidad  matlab.ui.control.Button
        lblTitulo                  matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        ANCHURAButton              matlab.ui.control.Button
        PROFUNDIDADButton          matlab.ui.control.Button
        SOLUCINLabel               matlab.ui.control.Label
        labelBoxTree               matlab.ui.control.Label
        Image_2                    matlab.ui.control.Image
        txtDescripcion             matlab.ui.control.TextArea
        txtTree                    matlab.ui.control.TextArea
        Image6                     matlab.ui.control.Image
        Image5                     matlab.ui.control.Image
        IniciarButton              matlab.ui.control.Button
        EstadoObjetivoLabel        matlab.ui.control.Label
        EstadoInicialLabel         matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            app.txtDescripcion.Value="";
            app.txtTree.Value="";
            system("anchurDef.py");
     
          % Abrir el archivo de texto
            fid = fopen('tree.txt', 'r');
            
            % Leer todo el contenido del archivo
            text = fscanf(fid, '%c');
            
            % Cerrar el archivo
            fclose(fid);
            
            app.txtTree.Value=text;
            
            fid = fopen('solution.txt', 'r');
            
            % Leer todo el contenido del archivo
            textlbl = fscanf(fid, '%c');
            
            % Cerrar el archivo
            fclose(fid);
            
            app.txtDescripcion.Value=textlbl;


        end

        % Button pushed function: ANCHURAButton
        function ANCHURAButtonPushed(app, event)
             app.txtDescripcion.Value="";
            app.txtTree.Value="";
            app.lblTitulo.Text="Búsqueda por ANCHURA";
            app.IniciarButton_Profundidad.Visible=false;
            app.labelBoxTree.Text="ÁRBOL GENERADO";


        end

        % Button pushed function: PROFUNDIDADButton
        function PROFUNDIDADButtonPushed(app, event)
            app.txtDescripcion.Value="";
            app.txtTree.Value="";
                app.lblTitulo.Text="Búsqueda por PROFUNDIDAD";
            app.IniciarButton_Profundidad.Visible=true;
            app.labelBoxTree.Text="CAMINO GENERADO";
        end

        % Button pushed function: IniciarButton_Profundidad
        function IniciarButton_ProfundidadPushed(app, event)
             system("Profundidad.py");
        fid = fopen('salida.txt', 'r');
        
        % Leer todo el contenido del archivo
        text = fscanf(fid, '%c');
        
        % Cerrar el archivo
        fclose(fid);
        
        app.txtTree.Value=text;
        
        fid = fopen('camino.txt', 'r');
        
        % Leer todo el contenido del archivo
        textlbl = fscanf(fid, '%c');
        
        % Cerrar el archivo
        fclose(fid);
        
        app.txtDescripcion.Value=textlbl;


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.PanelAyuda.Visible=true;
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            app.PanelAyuda.Visible=false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'estadoInicial.png');
            app.EstadoInicialLabel = uilabel(app.UIFigure);
            app.EstadoInicialLabel.Text = 'Estado Inicial';
            app.EstadoObjetivoLabel = uilabel(app.UIFigure);
            app.EstadoObjetivoLabel.Text = 'Estado Objetivo';
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'buscar.png');
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'flecha-correcta.png');
            app.txtTree = uitextarea(app.UIFigure);
            app.txtTree.Editable = 'off';
            app.txtDescripcion = uitextarea(app.UIFigure);
            app.txtDescripcion.Editable = 'off';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'estadofinal.png');
            app.labelBoxTree = uilabel(app.UIFigure);
            app.labelBoxTree.Text = 'ÁRBOL GENERADO ';
            app.SOLUCINLabel = uilabel(app.UIFigure);
            app.SOLUCINLabel.Text = 'SOLUCIÓN';
            app.PROFUNDIDADButton = uibutton(app.UIFigure, 'push');
            app.PROFUNDIDADButton.ButtonPushedFcn = createCallbackFcn(app, @PROFUNDIDADButtonPushed, true);
            app.PROFUNDIDADButton.Text = 'PROFUNDIDAD';
            app.ANCHURAButton = uibutton(app.UIFigure, 'push');
            app.ANCHURAButton.ButtonPushedFcn = createCallbackFcn(app, @ANCHURAButtonPushed, true);
            app.ANCHURAButton.Text = 'ANCHURA';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.lblTitulo = uilabel(app.UIFigure);
            app.lblTitulo.Text = 'Búsqueda por ANCHURA';
            app.IniciarButton_Profundidad = uibutton(app.UIFigure, 'push');
            app.IniciarButton_Profundidad.ButtonPushedFcn = createCallbackFcn(app, @IniciarButton_ProfundidadPushed, true);
            app.IniciarButton_Profundidad.Visible = 'off';
            app.IniciarButton_Profundidad.Text = 'Iniciar';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.PanelAyuda = uipanel(app.UIFigure);
            app.PanelAyuda.Title = 'Panel';
            app.PanelAyuda.Visible = 'off';
            app.AyudaLabel = uilabel(app.PanelAyuda);
            app.AyudaLabel.Text = 'Ayuda';
            app.CerrarButton = uibutton(app.PanelAyuda, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.TextArea = uitextarea(app.PanelAyuda);
            app.TextArea.Value = {'Este programa resuelve el problema del Puzzle con 8 piezas  por dos métodos, dónde de un estado inicial (imagen de la izquierda) debemos llegar a un estado objetivo (imagen de la derecha).'; ''; ''};
            app.Image8 = uiimage(app.PanelAyuda);
            app.Image8.ImageSource = fullfile(pathToMLAPP, '2.Anch.png');
            app.Image9 = uiimage(app.PanelAyuda);
            app.Image9.ImageSource = fullfile(pathToMLAPP, '1.Profundidad.png');
            app.Image7_2 = uiimage(app.PanelAyuda);
            app.Image7_2.ImageSource = fullfile(pathToMLAPP, '1Anch.png');
            app.SeleccionaelalgoritmodeseadoLabel = uilabel(app.PanelAyuda);
            app.SeleccionaelalgoritmodeseadoLabel.Text = '1. Selecciona el algoritmo deseado';
            app.EjecutaelprogramaLabel = uilabel(app.PanelAyuda);
            app.EjecutaelprogramaLabel.Text = '2. Ejecuta el programa';
            app.ObservalamagiaLabel = uilabel(app.PanelAyuda);
            app.ObservalamagiaLabel.Text = '3. Observa la magia';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end